classdef ParamsUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        DefaultparamsPushTool       matlab.ui.container.toolbar.PushTool
        HelpPushTool                matlab.ui.container.toolbar.PushTool
        SetparametersPanel          matlab.ui.container.Panel
        Showrejectedsubjects        matlab.ui.control.Hyperlink
        ButtonNext                  matlab.ui.control.Button
        ButtonBack                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        GeneralsTab                 matlab.ui.container.Tab
        GeneralsparamsPanel         matlab.ui.container.Panel
        LabelReqParticipantsInfo    matlab.ui.control.Label
        ParticipantsFileEditField   matlab.ui.control.EditField
        filenameLabel_3             matlab.ui.control.Label
        ParticipantsFileButton      matlab.ui.control.Button
        SaveModeSwitch              matlab.ui.control.Switch
        ParallelprocessLabel_5      matlab.ui.control.Label
        LabelReqDataType            matlab.ui.control.Label
        DatatypeLabel               matlab.ui.control.Label
        DataTypeDropDown            matlab.ui.control.DropDown
        ParallelProcessSwitch       matlab.ui.control.Switch
        ParallelprocessLabel        matlab.ui.control.Label
        LabelReqRefFile             matlab.ui.control.Label
        RefFileEditField            matlab.ui.control.EditField
        filenameLabel_2             matlab.ui.control.Label
        RefFileButton               matlab.ui.control.Button
        LabelReqOutput              matlab.ui.control.Label
        LabelReqInputPath           matlab.ui.control.Label
        LabelReqDatasetName         matlab.ui.control.Label
        DatasetDescriptionTextArea  matlab.ui.control.TextArea
        DatasetdescriptionLabel     matlab.ui.control.Label
        DatasetnameEditField        matlab.ui.control.EditField
        DatasetnameEditFieldLabel   matlab.ui.control.Label
        TmpPathEditField            matlab.ui.control.EditField
        TmppathLabel                matlab.ui.control.Label
        InputPathEditField          matlab.ui.control.EditField
        InputpathLabel              matlab.ui.control.Label
        TmpPathButton               matlab.ui.control.Button
        InputPathButton             matlab.ui.control.Button
        OutputPathEditField         matlab.ui.control.EditField
        OutputpathEditFieldLabel    matlab.ui.control.Label
        OutputPathButton            matlab.ui.control.Button
        ParticipantsTab             matlab.ui.container.Tab
        LabelInfo                   matlab.ui.control.Label
        ParticipantsUITable         matlab.ui.control.Table
        AnatomyTab                  matlab.ui.container.Tab
        UseDefaultAnatSwitch        matlab.ui.control.Switch
        ParallelprocessLabel_2      matlab.ui.control.Label
        AnatPanel                   matlab.ui.container.Panel
        LabelReqCondDelay           matlab.ui.control.Label
        CondDelayFileButton         matlab.ui.control.Button
        CondDelayFileEditField      matlab.ui.control.EditField
        ConddelayLabel              matlab.ui.control.Label
        LabelReqNeuroTracs          matlab.ui.control.Label
        NeuroTractFileButton        matlab.ui.control.Button
        NeuroTractFileEditField     matlab.ui.control.EditField
        NeuroTractLabel             matlab.ui.control.Label
        LabelReqAnatConn            matlab.ui.control.Label
        AnatConnFileButton          matlab.ui.control.Button
        AnatConnFileField           matlab.ui.control.EditField
        AnatconnLabel               matlab.ui.control.Label
        LabelReqChannels            matlab.ui.control.Label
        ChannelsFileButton          matlab.ui.control.Button
        ChannelsFileEditField       matlab.ui.control.EditField
        ChannelsfileLabel           matlab.ui.control.Label
        LabelReqLeadfild            matlab.ui.control.Label
        LeadfieldFileButton         matlab.ui.control.Button
        LeadfieldFileEditField      matlab.ui.control.EditField
        LeadfieldfileLabel          matlab.ui.control.Label
        LabelReqCortex              matlab.ui.control.Label
        CortexFileButton            matlab.ui.control.Button
        CortexFileEditField         matlab.ui.control.EditField
        CortexfileLabel             matlab.ui.control.Label
        ModelTab                    matlab.ui.container.Tab
        GroupAnalysisPanel          matlab.ui.container.Panel
        InsertAgeRangeButton        matlab.ui.control.Button
        DeleteAgeRangeButton        matlab.ui.control.Button
        AddAgeRangeButton           matlab.ui.control.Button
        AgeRangeUITable             matlab.ui.control.Table
        ModelparamsButtonGroup      matlab.ui.container.ButtonGroup
        NumberofFrequenciesSpinner  matlab.ui.control.Spinner
        NumberofFrequenciesSpinnerLabel  matlab.ui.control.Label
        Stoch2Switch                matlab.ui.control.Switch
        Stoch2SwitchLabel           matlab.ui.control.Label
        Stoch1Switch                matlab.ui.control.Switch
        ParallelprocessLabel_4      matlab.ui.control.Label
        DefaultTensorFieldSwitch    matlab.ui.control.Switch
        ParallelprocessLabel_3      matlab.ui.control.Label
        BayesIter_Reg2Spinner       matlab.ui.control.Spinner
        BayesIter_Reg2Label         matlab.ui.control.Label
        BayesIter_Reg1Spinner       matlab.ui.control.Spinner
        BayesIter_Reg1Label         matlab.ui.control.Label
        Nrand2Spinner               matlab.ui.control.Spinner
        Nrand2Label                 matlab.ui.control.Label
        Nrand1Spinner               matlab.ui.control.Spinner
        Nrand1Label                 matlab.ui.control.Label
        BayesIterationDelaySpinner  matlab.ui.control.Spinner
        BayesIterationDelayLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_properties % Description
        xan_dir % Description
        rejected_subs        
        ref_sub % Description
    end
    
    properties (Access = public)
        last_dir % Description
        Finished % Description
    end
    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            %% General tab
            if(isempty(app.DatasetnameEditField.Value))
                 msgbox({'The Dataset name can not be empty',...
                    ' Please type a Dataset name.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.InputPathEditField.Value))
                msgbox({'The Input path can not be empty.',...
                    ' Please select a correct Input path.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.RefFileEditField.Value))
                msgbox({'The Reference file can not be empty.',...
                    ' Please select a correct refenrece file.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.ParticipantsFileEditField.Value))
                msgbox({'The Participants file can not be empty.',...
                    ' Please select a correct Participants file.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.TmpPathEditField.Value))
                msgbox({'The Temporal path can not be empty.',...
                    ' Please select a correct Temporal path.'},'Info');
                app.TmpPathEditField.Value = 'local';
                checked = false;
                return;
            end           
            if(isempty(app.OutputPathEditField.Value))
                msgbox({'The Output path can not be empty.',...
                    ' Please select a correct Output path.'},'Info');
                checked = false;
                return;
            end            
            if(isequal(app.DataTypeDropDown.Value,'--Select--'))
                msgbox({'The Data type can not be empty.',...
                    ' Please select a correct Data type.'},'Info');
                checked = false;
                return;
            end
            
            %% Participants tab
            indxs = find(table2array(app.ParticipantsUITable.Data(:,1)));
            if(isempty(indxs))
                msgbox({'The Participants can not be empty.',...
                    ' Please select at least one participant.'},'Info');
                checked = false;
                return;
            end            

             %% Anatomy tab
             if(~app.UseDefaultAnatSwitch.Value)
                 if(isempty(app.LeadfieldFileEditField.Value))
                     msgbox({'The Leadfield file can not be empty',...
                         ' Please select a correct Leadfield file.'},'Info');
                     checked = false;
                     return;
                 end
                  if(isempty(app.CortexFileEditField.Value))
                     msgbox({'The Cortex file can not be empty',...
                         ' Please select a correct Cortex file.'},'Info');
                     checked = false;
                     return;
                  end
                  if(isempty(app.ChannelsFileEditField.Value))
                     msgbox({'The Channels file can not be empty',...
                         ' Please select a correct Channels file.'},'Info');
                     checked = false;
                     return;
                  end
                  if(isempty(app.AnatConnFileField.Value))
                     msgbox({'The Anatomical Conn file can not be empty',...
                         ' Please select a correct Anatomical Conn file.'},'Info');
                     checked = false;
                     return;
                  end
                  if(isempty(app.NeuroTractFileEditField.Value))
                     msgbox({'The Neuro tracs file can not be empty',...
                         ' Please select a correct Neuro tracs file.'},'Info');
                     checked = false;
                     return;
                  end
                  if(isempty(app.CondDelayFileEditField.Value))
                     msgbox({'The Cond Delay file can not be empty',...
                         ' Please select a correct Cond Delay file.'},'Info');
                     checked = false;
                     return;
                  end
             end
        end
        
        function load_predef_params(app)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            %% Filling components from predefined params
            % General params
            general_params                              = app.app_properties.general_params;
            app.DatasetnameEditField.Value              = general_params.dataset.Name;
            app.DatasetDescriptionTextArea.Value        = general_params.dataset.Description;
            
            if(xan_check_properties('Input_path',general_params.input_path))
                app.InputPathEditField.Value            = general_params.input_path;
            end
            if(xan_check_properties('Ref_file',general_params.input_path,general_params.data.ref_file))
                app.RefFileEditField.Value              = general_params.data.ref_file;
            end
            if(xan_check_properties('Part_file',general_params.input_path,general_params.dataset.participants_info.file))
                app.ParticipantsFileEditField.Value     = general_params.dataset.participants_info.file;
            end
            items = app.DataTypeDropDown.ItemsData;
            indItem = find(ismember(items,general_params.data.type));
            if(isempty(indItem))
                app.DataTypeDropDown.Value = app.DataTypeDropDown.ItemsData{1};
            else
                app.DataTypeDropDown.Value = app.DataTypeDropDown.ItemsData{indItem};
            end
            if(xan_check_properties('Output_path',general_params.output_path))
                app.OutputPathEditField.Value        = general_params.output_path;
            end
            if(xan_check_properties('Tmp_path',general_params.tmp.path))
                app.TmpPathEditField.Value       = general_params.tmp.path;
            end

            if(general_params.parallel.conn_delay)
                app.ParallelProcessSwitch.Value = 'Yes';
                app.ParallelProcessSwitch.FontColor = 'b';
            else
                app.ParallelProcessSwitch.Value = 'No';
                app.ParallelProcessSwitch.FontColor = 'k';
            end

            if(general_params.save_mode.extended)
                app.SaveModeSwitch.Value = 'Extended';
                app.SaveModeSwitch.FontColor = 'b';
            else
                app.SaveModeSwitch.Value = 'Basic';
                app.SaveModeSwitch.FontColor = 'k';
            end

            % Participants
            app.FillParticipantsTable();

            % Anatomy params
            anatomy_params = app.app_properties.anatomy_params;
            if(anatomy_params.use_default)
                app.UseDefaultAnatSwitch.Value = 'Yes';
                app.UseDefaultAnatSwitch.FontColor = 'b';
                app.AnatPanel.Enable       = 'off';
                if(xan_check_properties('Cortex',fullfile(pwd,anatomy_params.cortex.file_name)))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfield',fullfile(pwd,anatomy_params.leadfield.file_name)))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',fullfile(pwd,anatomy_params.channel.file_name)))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',fullfile(pwd,anatomy_params.conn_anat.file_name)))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',fullfile(pwd,anatomy_params.neuroTracLengths.file_name)))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',fullfile(pwd,anatomy_params.cond_delay.file_name)))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            else
                app.UseDefaultAnatSwitch.Value = 'No';
                app.UseDefaultAnatSwitch.FontColor = 'k';
                app.AnatPanel.Enable       = 'on';

                if(xan_check_properties('Cortex',anatomy_params.cortex.file_name))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfild',anatomy_params.leadfield.file_name))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',anatomy_params.channel.file_name))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',anatomy_params.conn_anat.file_name))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',anatomy_params.neuroTracLengths.file_name))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',anatomy_params.cond_delay.file_name))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            end

            % Model params
            model_params  = app.app_properties.model_params;
            if(model_params.tensor_field.default)
                app.DefaultTensorFieldSwitch.Value = 'Yes';
                app.DefaultTensorFieldSwitch.FontColor = 'b';
            else
                app.DefaultTensorFieldSwitch.Value = 'No';
                app.DefaultTensorFieldSwitch.FontColor = 'k';
            end

            if(model_params.nFreqs >= app.NumberofFrequenciesSpinner.Limits(1) && model_params.nFreqs <= app.NumberofFrequenciesSpinner.Limits(2))
                app.NumberofFrequenciesSpinner.Value = model_params.nFreqs;
            end
            if(model_params.BayesIter_Delay >= app.BayesIterationDelaySpinner.Limits(1) && model_params.BayesIter_Delay <= app.BayesIterationDelaySpinner.Limits(2))
                app.BayesIterationDelaySpinner.Value = model_params.BayesIter_Delay;
            end
            if(model_params.Nrand1 >= app.Nrand1Spinner.Limits(1) && model_params.Nrand1 <= app.Nrand1Spinner.Limits(2))
                app.Nrand1Spinner.Value = model_params.Nrand1;
            end
            if(model_params.Nrand2 >= app.Nrand2Spinner.Limits(1) && model_params.Nrand2 <= app.Nrand2Spinner.Limits(2))
                app.Nrand2Spinner.Value = model_params.Nrand2;
            end
            if(model_params.BayesIter_Reg1 >= app.BayesIter_Reg1Spinner.Limits(1) && model_params.BayesIter_Reg1 <= app.BayesIter_Reg1Spinner.Limits(2))
                app.BayesIter_Reg1Spinner.Value = model_params.BayesIter_Reg1;
            end
            if(model_params.BayesIter_Reg2 >= app.BayesIter_Reg2Spinner.Limits(1) && model_params.BayesIter_Reg2 <= app.BayesIter_Reg2Spinner.Limits(2))
                app.BayesIter_Reg2Spinner.Value = model_params.BayesIter_Reg2;
            end
            
            if(model_params.stoch1)
                app.Stoch1Switch.Value = 'True';
                app.Stoch1Switch.FontColor = 'b';
            else
                app.Stoch1Switch.Value = 'False';
                app.Stoch1Switch.FontColor = 'k';
            end

            if(model_params.stoch1)
                app.Stoch2Switch.Value = 'True';
                app.Stoch2Switch.FontColor = 'b';
            else
                app.Stoch2Switch.Value = 'False';
                app.Stoch2Switch.FontColor = 'k';
            end    

            % Age range
            ranges = app.app_properties.model_params.group.age_range;   
            for i=1:length(ranges)
                range = ranges{i};                 
                startAges{i} = range{1};
                endAges{i} = range{2};
                descriptions{i} = range{3};                
            end
            indxs = num2cell([1:length(ranges)]);
            cbxs = true(size(ranges,1),1);            
            T = table(indxs',cbxs,startAges',endAges',descriptions');
            app.AgeRangeUITable.Data = T;

        end
        
        function update_property_files(app)
            %% General tab
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            general_params                                  = app.app_properties.general_params;
            general_params.dataset.Name                     = app.DatasetnameEditField.Value;
            general_params.dataset.Description              = app.DatasetDescriptionTextArea.Value;
            general_params.dataset.participants_info.file   = app.ParticipantsFileEditField.Value;
            general_params.input_path                       = app.InputPathEditField.Value;
            general_params.output_path                      = app.OutputPathEditField.Value;
            general_params.parallel.conn_delay              = isequal(app.ParallelProcessSwitch.Value,'Yes');
            general_params.data.ref_file                    = app.RefFileEditField.Value;
            general_params.data.type                        = app.DataTypeDropDown.Value;
            general_params.tmp.path                         = app.TmpPathEditField.Value;  
            general_params.save_mode.extended               = isequal(app.SaveModeSwitch.Value,'Extended');
            indxs                                           = find(table2array(app.ParticipantsUITable.Data(:,1)));
            subjects                                        = table2array(app.ParticipantsUITable.Data(indxs,2));
            general_params.participants                     = subjects;              

            % Saving property files
            saveJSON(general_params,app.app_properties.module_param_files(1).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(1).file_path));
            h.smartIndentContents
            h.save
            h.close

            %% Import anatomy tab
            anatomy_params                          = app.app_properties.anatomy_params;
            if(isequal(app.UseDefaultAnatSwitch.Value,'Yes'))
                anatomy_params                      = app.app_properties.defaults.anatomy_params;
            else
                anatomy_params.use_default          = false;
                anatomy_params.cortex               = app.CortexFileEditField.Value;
                anatomy_params.leadfield            = app.LeadfieldFileEditField.Value;
                anatomy_params.channel              = app.ChannelsFileEditField.Value;
                anatomy_params.conn_anat            = app.AnatConnFileField.Value;
                anatomy_params.neuroTracLengths     = app.NeuroTractFileEditField.Value;
                anatomy_params.cond_delay           = app.CondDelayFileEditField.Value;
            end
            % Saving property files
            saveJSON(anatomy_params,app.app_properties.module_param_files(2).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(2).file_path));
            h.smartIndentContents
            h.save
            h.close

            %% Model tab
            model_params                        = app.app_properties.model_params;
            model_params.tensor_field.default   = isequal(app.DefaultTensorFieldSwitch.Value,'Yes');
            model_params.nFreqs                 = app.NumberofFrequenciesSpinner.Value;
            model_params.BayesIter_Delay        = app.BayesIterationDelaySpinner.Value;
            model_params.Nrand1                 = app.Nrand1Spinner.Value;
            model_params.Nrand2                 = app.Nrand2Spinner.Value;
            model_params.BayesIter_Reg1         = app.BayesIter_Reg1Spinner.Value;
            model_params.BayesIter_Reg2         = app.BayesIter_Reg2Spinner.Value;
            model_params.stoch1                 = isequal(app.Stoch1Switch.Value,'True');
            model_params.stoch2                 = isequal(app.Stoch2Switch.Value,'True');
            % Age range
            indxs                               = find(table2array(app.AgeRangeUITable.Data(:,2)));
            data = app.AgeRangeUITable.Data(indxs,3:end);  
            for i=1:size(data,1)
                ranges{i} = {data.Var3{i};data.Var4{i};data.Var5{i}};
            end
            model_params.group.age_range        = ranges';   

             % Saving property files
            saveJSON(model_params,app.app_properties.module_param_files(3).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(3).file_path));
            h.smartIndentContents
            h.save
            h.close           
        end        
                
        function response = ShowParameters(app)
            response = true;
            general_params = app.app_properties.general_params;
            
            anatomy_params = app.app_properties.anatomy_params;

            model_params = app.app_properties.model_params;
        end
        
        function SelectAll(app, cbx)
            if(cbx.Value)
                app.ParticipantsUITable.Data{:,1} = true(size(app.ParticipantsUITable.Data(:,1)));
            else
                app.ParticipantsUITable.Data{:,1} = false(size(app.ParticipantsUITable.Data(:,1)));
            end
            total = size(app.ParticipantsUITable.Data(:,1),1);
            selected = length(find(table2array(app.ParticipantsUITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end
        
        function FillParticipantsTable(app)
            % Filling table
            subjects = dir(app.InputPathEditField.Value);
            subjects(ismember({subjects.name},{'.','..','derivatives','.git'})) = [];
            subjects(~[subjects.isdir]) = [];
            subs = {subjects.name}';
            reject = [];
            for i=1:length(subs)
                sub = subs{i};
                file = fullfile(app.InputPathEditField.Value,sub,strrep(app.RefFileEditField.Value,'SubID',sub));
                if(~isfile(file))
                    reject = [reject i];
                end
            end
            app.rejected_subs = subjects(reject);
            app.Showrejectedsubjects.Enable = false;
            if(~isempty(subs))
                app.Showrejectedsubjects.Enable = true;
                subs(reject) = [];
                cbxs = true(size(subs));
                [~,name,ext] = fileparts(app.RefFileEditField.Value);
                files = {};
                for i=1:length(subs)
                    files{i}= strcat(strrep(name,'SubID',subs{i}),ext);
                end
                T = table(cbxs,subs,files');
                app.ParticipantsUITable.Data = T;
                if(isequal(app.ParticipantsTab.Children(1).Tag,'CheckAll'))
                    delete(app.ParticipantsTab.Children(1))
                end
                uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[107,385,30,30],Parent=app.ParticipantsTab,...
                    ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                total = size(subs,1);
                selected = length(find(table2array(app.ParticipantsUITable.Data(:,1))));
                app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
            end
        end
        
        % Getting default properties and seting on user interface
        function set_default_properties(app)         
            app.app_properties.general_params          = app.app_properties.defaults.general_params;
            app.app_properties.anatomy_params          = app.app_properties.defaults.anatomy_params;
            app.app_properties.model_params             = app.app_properties.defaults.model_params;
            % Saving property files
            saveJSON(app.app_properties.general_params,app.app_properties.module_param_files(1).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(1).file_path));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.anatomy_params,app.app_properties.module_param_files(2).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(2).file_path));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.model_params,app.app_properties.module_param_files(3).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(3).file_path));
            h.smartIndentContents
            h.save
            h.close 
        end % set_default_properties
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            app.UIFigure.Name = 'Params Configuration';
            app.Finished = false;
            movegui(app.UIFigure,"center");
            app.xan_dir = xan_get('xan_dir');
            app.last_dir = getUserDir();
            app.app_properties = get_properties();
            % load prodefinition parameters
            app.load_predef_params();
            
        end

        % Button pushed function: InputPathButton
        function InputPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Input path");
                folder = strrep(folder,'\','/');
                app.InputPathEditField.Value = folder;
                app.last_dir = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: TmpPathButton
        function TmpPathButtonPushed(app, event)
            try
                folder = uigetdir(app.last_dir,"Select the Temporal folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.TmpPathEditField.Value = strrep(folder,'\','/');
                    app.last_dir = folder;
                else
                    app.TmpPathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected folder.',...
                        ' Please check the folder permission or select another tmp folder.'},'Info');
                end
            catch
                app.TmpPathEditField.Value = 'local';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: OutputPathButton
        function OutputPathButtonPushed(app, event)
            try
                folder = uigetdir(app.last_dir,"Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.OutputPathEditField.Value = strrep(folder,'\','/');
                    app.last_dir = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected folder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;
        end

        % Selection changed function: ModelparamsButtonGroup
        function ModelparamsButtonGroupSelectionChanged(app, event)
            if(app.Button_Spheres.Value)
                app.SpheresoptionsPanel.Enable = 'on';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_OpenMEEGBEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_DUNEuroFEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'on';
            end
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Participants'))
                app.TabGroup.SelectedTab = app.GeneralsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Anatomy'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Model'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Generals'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Participants'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Anatomy'))
                app.TabGroup.SelectedTab = app.ModelTab;
                return;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checked = app.check_visual_params();
            if(checked)
                response = app.ShowParameters();
                if(response)
                    app.update_property_files();
                    app.Finished = true;
                    app.UIFigure.Visible = false;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;           
        end

        % Button pushed function: RefFileButton
        function RefFileButtonPushed(app, event)
            try
                if(isempty(app.InputPathEditField.Value))
                    msgbox('Select first the Input path.','Info');
                    return;
                end
                root_path = app.InputPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.mat';'*.PLG';'*.edf';'*.set';'*.vhdr';'*.txt'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.InputPathEditField.Value;
                base_path = strrep(base_path,'\','/');
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info'); 
                    figure(app.UIFigure);
                    return;
                end

                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name); 

                app.ref_sub.SubID = SubID;
                app.ref_sub.Path = root_path;
                app.ref_sub.File = ref_path;
                ref_path = strrep(ref_path,SubID,'SubID');
                app.RefFileEditField.Value = ref_path;  

                % Filling Participants table
                app.FillParticipantsTable();  
            catch
                app.RefFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Value changed function: UseDefaultAnatSwitch
        function UseDefaultAnatSwitchValueChanged(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            value = app.UseDefaultAnatSwitch.Value;
            % Anatomy params
            anatomy_params = app.app_properties.anatomy_params;
            default_anatomy_patams = app.app_properties.defaults.anatomy_params;
            if(isequal(value,'Yes'))
                app.UseDefaultAnatSwitch.FontColor = 'b';
                app.AnatPanel.Enable       = 'off';
                if(xan_check_properties('Cortex',fullfile(pwd,anatomy_params.cortex.file_name)))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfield',fullfile(pwd,anatomy_params.leadfield.file_name)))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',fullfile(pwd,anatomy_params.channel.file_name)))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',fullfile(pwd,anatomy_params.conn_anat.file_name)))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',fullfile(pwd,anatomy_params.neuroTracLengths.file_name)))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',fullfile(pwd,anatomy_params.cond_delay.file_name)))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            else                
                app.UseDefaultAnatSwitch.FontColor = 'k';
                app.AnatPanel.Enable       = 'on';

                if(xan_check_properties('Cortex',default_anatomy_patams.cortex.file_name))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfield',default_anatomy_patams.leadfield.file_name))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',default_anatomy_patams.channel.file_name))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',default_anatomy_patams.conn_anat.file_name))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',default_anatomy_patams.neuroTracLengths.file_name))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',default_anatomy_patams.cond_delay.file_name))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            end
        end

        % Button pushed function: CortexFileButton
        function CortexFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a cortex file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('cortex',fullfile(file_path,file_name));
                if(checked)
                    app.CortexFileEditField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.CortexFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Cortex structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.CortexFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.CortexFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: LeadfieldFileButton
        function LeadfieldFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a leadfield file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('leadfield',fullfile(file_path,file_name));
                if(checked)
                    app.LeadfieldFileEditField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.LeadfieldFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Leadfield structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.LeadfieldFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.LeadfieldFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ChannelsFileButton
        function ChannelsFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a channels file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('channels',fullfile(file_path,file_name));
                if(checked)
                    app.ChannelsFileEditField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.ChannelsFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Channels structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.ChannelsFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.ChannelsFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: AnatConnFileButton
        function AnatConnFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a Anatomical connectivity file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('conn_anat',fullfile(file_path,file_name));
                if(checked)
                    app.AnatConnFileField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.AnatConnFileField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a correct Anatomical conn structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.AnatConnFileField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.AnatConnFileField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: NeuroTractFileButton
        function NeuroTractFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a Neuro tracs file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('trac',fullfile(file_path,file_name));
                if(checked)
                    app.NeuroTractFileEditField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.NeuroTractFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Neuro tracs structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.NeuroTractFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.NeuroTractFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CondDelayFileButton
        function CondDelayFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a delay file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('leadfield',fullfile(file_path,file_name));
                if(checked)
                    app.CondDelayFileEditField.Value = strrep(fullfile(file_path,file_name),'\','/');
                    app.CondDelayFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("+guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Delay structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.CondDelayFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.CondDelayFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Callback function: DefaultparamsPushTool
        function DefaultparamsPushToolClicked(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = {strcat("You will restore the default params"), ...
                'Would you like continue?'};
            answer = questdlg(quest,'Apply changes',...
                'Yes','No',opts);
            % Handle response
            switch answer
                case 'Yes'
                    set_default_properties(app);
                    app.app_properties = get_properties();
                    % load prodefinition parameters
                    load_predef_params(app);
                case 'No'
            end
        end

        % Button pushed function: AddAgeRangeButton
        function AddAgeRangeButtonPushed(app, event)
            indx = size(app.AgeRangeUITable.Data,1) + 1;
            newData = [app.AgeRangeUITable.Data; {indx,false,0,0,''}];
            app.AgeRangeUITable.Data = newData;
        end

        % Button pushed function: DeleteAgeRangeButton
        function DeleteAgeRangeButtonPushed(app, event)
            indx = app.AgeRangeUITable.Selection;
            if(isempty(indx))
                msgbox({'Please select the row position in the table.'},'Info');
                return;
            else
                msg = "Are you sure to delete the age range?";
                title = "Delete age range";
                answer = uiconfirm(app.UIFigure,msg,title, ...
                    "Options",["Delete","Cancel"], ...
                    "Icon","+guide/images/insert_row.png",...
                    "DefaultOption",1,"CancelOption",2);
                % Handle response
                switch answer
                    case 'Delete'
                        data = app.AgeRangeUITable.Data;
                        data(indx,:) = []; 
                        app.AgeRangeUITable.Data = data;
                    case 'Cancel'
                        return;
                    otherwise
                        return;

                end
            end
        end

        % Button pushed function: InsertAgeRangeButton
        function InsertAgeRangeButtonPushed(app, event)
            indx = app.AgeRangeUITable.Selection;
            if(isempty(indx))
                msgbox({'Please select the row position in the table.'},'Info');
                return;
            else
                msg = "Would you like to insert a new age range?";
                title = "Insert new age range";
                answer = uiconfirm(app.UIFigure,msg,title, ...
                    "Options",["Insert","Cancel"], ...
                    "Icon","+guide/images/insert_row.png",...
                    "DefaultOption",1,"CancelOption",2);
                % Handle response
                switch answer
                    case 'Insert'
                        data = app.AgeRangeUITable.Data;
                        first = data(1:indx-1,:);
                        newRow = {indx,false,0,0,''};
                        last = data(indx:end,:);
                        newData = [first; newRow; last];
                        indxs = num2cell([1:size(newData,1)]);
                        newData.Var1 =  indxs';
                        app.AgeRangeUITable.Data = newData;
                    case 'Cancel'
                        return;
                    otherwise
                        return;

                end
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            value = app.DataTypeDropDown.Value;
            if(isequal(value,'eeg_signal'))
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/waiting.gif', ...
                    'Title','Please wait...', ...
                    'Message','EEG parameters', ...
                    'Indeterminate','on');
                obj_Data = DataDescUI();
                waitfor(obj_Data,'Finished',true);
                if(obj_Data.Applied)
                    app.app_properties.general_params.data = obj_Data.data_params;
                else
                    app.DataTypeDropDown.Value = app.app_properties.general_params.data.type;
                end
                delete(obj_Data);  
                close(dlg);
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ParticipantsFileButton
        function ParticipantsFileButtonPushed(app, event)
            try
                if(isempty(app.InputPathEditField.Value))
                    msgbox('Select first the Input path.','Info');
                    return;
                end
                root_path = app.InputPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.json';'*.csv';'*.tsv'},'Select tha Participants Information file',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.InputPathEditField.Value;
                base_path = strrep(base_path,'\','/');
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info'); 
                    figure(app.UIFigure);
                    return;
                end
                ref_path = fullfile(file_parts{2},file_name);
                app.ParticipantsFileEditField.Value = ref_path;                   
            catch
                app.RefFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.DefaultparamsPushTool = uipushtool(app.Toolbar);
            app.DefaultparamsPushTool.ClickedCallback = createCallbackFcn(app, @DefaultparamsPushToolClicked, true);
            app.DefaultparamsPushTool.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.Icon = fullfile(pathToMLAPP, 'images', 'help.png');
            app.SetparametersPanel = uipanel(app.UIFigure);
            app.SetparametersPanel.Title = 'Set parameters';
            app.TabGroup = uitabgroup(app.SetparametersPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.OutputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.OutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPathButtonPushed, true);
            app.OutputPathButton.Text = 'Select';
            app.OutputpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.OutputpathEditFieldLabel.Text = 'Output path:';
            app.OutputPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.OutputPathEditField.Editable = 'off';
            app.InputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.InputPathButton.ButtonPushedFcn = createCallbackFcn(app, @InputPathButtonPushed, true);
            app.InputPathButton.Text = 'Select';
            app.TmpPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.TmpPathButton.ButtonPushedFcn = createCallbackFcn(app, @TmpPathButtonPushed, true);
            app.TmpPathButton.Text = 'Select';
            app.InputpathLabel = uilabel(app.GeneralsparamsPanel);
            app.InputpathLabel.Text = 'Input path:';
            app.InputPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.InputPathEditField.Editable = 'off';
            app.TmppathLabel = uilabel(app.GeneralsparamsPanel);
            app.TmppathLabel.Text = 'Tmp path:';
            app.TmpPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.TmpPathEditField.Editable = 'off';
            app.TmpPathEditField.Value = 'local';
            app.DatasetnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name:';
            app.DatasetnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.DatasetdescriptionLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetdescriptionLabel.Text = 'DS. description:';
            app.DatasetDescriptionTextArea = uitextarea(app.GeneralsparamsPanel);
            app.LabelReqDatasetName = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDatasetName.Text = '*';
            app.LabelReqInputPath = uilabel(app.GeneralsparamsPanel);
            app.LabelReqInputPath.Text = '*';
            app.LabelReqOutput = uilabel(app.GeneralsparamsPanel);
            app.LabelReqOutput.Text = '*';
            app.RefFileButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.RefFileButton.ButtonPushedFcn = createCallbackFcn(app, @RefFileButtonPushed, true);
            app.RefFileButton.Tooltip = {'Reference file for one subject'};
            app.RefFileButton.Text = 'Select';
            app.filenameLabel_2 = uilabel(app.GeneralsparamsPanel);
            app.filenameLabel_2.Text = 'Reference file:';
            app.RefFileEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.RefFileEditField.Editable = 'off';
            app.LabelReqRefFile = uilabel(app.GeneralsparamsPanel);
            app.LabelReqRefFile.Text = '*';
            app.ParallelprocessLabel = uilabel(app.GeneralsparamsPanel);
            app.ParallelprocessLabel.Text = 'Parallel process:';
            app.ParallelProcessSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.ParallelProcessSwitch.Items = {'No', 'Yes'};
            app.ParallelProcessSwitch.Tooltip = {''};
            app.ParallelProcessSwitch.Value = 'No';
            app.DataTypeDropDown = uidropdown(app.GeneralsparamsPanel);
            app.DataTypeDropDown.Items = {'--Select--', 'Cross', 'EEG signal'};
            app.DataTypeDropDown.ItemsData = {'--Select--', 'cross', 'eeg_signal'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Tooltip = {''};
            app.DataTypeDropDown.Value = '--Select--';
            app.DatatypeLabel = uilabel(app.GeneralsparamsPanel);
            app.DatatypeLabel.Text = 'Data type:';
            app.LabelReqDataType = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDataType.Text = '*';
            app.ParallelprocessLabel_5 = uilabel(app.GeneralsparamsPanel);
            app.ParallelprocessLabel_5.Text = 'Save mode:';
            app.SaveModeSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.SaveModeSwitch.Items = {'Basic', 'Extended'};
            app.SaveModeSwitch.Tooltip = {'Yes - '; 'No - '};
            app.SaveModeSwitch.Value = 'Basic';
            app.ParticipantsFileButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.ParticipantsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantsFileButtonPushed, true);
            app.ParticipantsFileButton.Tooltip = {'Participants file including the information of the Datasets participants'};
            app.ParticipantsFileButton.Text = 'Select';
            app.filenameLabel_3 = uilabel(app.GeneralsparamsPanel);
            app.filenameLabel_3.Text = 'Participants file:';
            app.ParticipantsFileEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.ParticipantsFileEditField.Editable = 'off';
            app.ParticipantsFileEditField.Tooltip = {'Participants file including the information of the Datasets participants'};
            app.LabelReqParticipantsInfo = uilabel(app.GeneralsparamsPanel);
            app.LabelReqParticipantsInfo.Text = '*';
            app.ParticipantsTab = uitab(app.TabGroup);
            app.ParticipantsTab.Title = 'Participants';
            app.ParticipantsUITable = uitable(app.ParticipantsTab);
            app.ParticipantsUITable.ColumnName = {'Select'; 'Subjects'; 'File'};
            app.ParticipantsUITable.RowName = {};
            app.ParticipantsUITable.ColumnEditable = [true false false];
            app.LabelInfo = uilabel(app.ParticipantsTab);
            app.LabelInfo.Text = '';
            app.AnatomyTab = uitab(app.TabGroup);
            app.AnatomyTab.Title = 'Anatomy';
            app.AnatPanel = uipanel(app.AnatomyTab);
            app.AnatPanel.Title = 'Anatomy params';
            app.CortexfileLabel = uilabel(app.AnatPanel);
            app.CortexfileLabel.Text = 'Cortex file:';
            app.CortexFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CortexFileEditField.Editable = 'off';
            app.CortexFileButton = uibutton(app.AnatPanel, 'push');
            app.CortexFileButton.ButtonPushedFcn = createCallbackFcn(app, @CortexFileButtonPushed, true);
            app.CortexFileButton.Text = 'Select';
            app.LabelReqCortex = uilabel(app.AnatPanel);
            app.LabelReqCortex.Text = '*';
            app.LeadfieldfileLabel = uilabel(app.AnatPanel);
            app.LeadfieldfileLabel.Text = 'Leadfield file:';
            app.LeadfieldFileEditField = uieditfield(app.AnatPanel, 'text');
            app.LeadfieldFileEditField.Editable = 'off';
            app.LeadfieldFileButton = uibutton(app.AnatPanel, 'push');
            app.LeadfieldFileButton.ButtonPushedFcn = createCallbackFcn(app, @LeadfieldFileButtonPushed, true);
            app.LeadfieldFileButton.Text = 'Select';
            app.LabelReqLeadfild = uilabel(app.AnatPanel);
            app.LabelReqLeadfild.Text = '*';
            app.ChannelsfileLabel = uilabel(app.AnatPanel);
            app.ChannelsfileLabel.Text = 'Channels file:';
            app.ChannelsFileEditField = uieditfield(app.AnatPanel, 'text');
            app.ChannelsFileEditField.Editable = 'off';
            app.ChannelsFileButton = uibutton(app.AnatPanel, 'push');
            app.ChannelsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelsFileButtonPushed, true);
            app.ChannelsFileButton.Text = 'Select';
            app.LabelReqChannels = uilabel(app.AnatPanel);
            app.LabelReqChannels.Text = '*';
            app.AnatconnLabel = uilabel(app.AnatPanel);
            app.AnatconnLabel.Text = 'Anat. conn:';
            app.AnatConnFileField = uieditfield(app.AnatPanel, 'text');
            app.AnatConnFileField.Editable = 'off';
            app.AnatConnFileButton = uibutton(app.AnatPanel, 'push');
            app.AnatConnFileButton.ButtonPushedFcn = createCallbackFcn(app, @AnatConnFileButtonPushed, true);
            app.AnatConnFileButton.Text = 'Select';
            app.LabelReqAnatConn = uilabel(app.AnatPanel);
            app.LabelReqAnatConn.Text = '*';
            app.NeuroTractLabel = uilabel(app.AnatPanel);
            app.NeuroTractLabel.Text = 'Neuro Tract:';
            app.NeuroTractFileEditField = uieditfield(app.AnatPanel, 'text');
            app.NeuroTractFileEditField.Editable = 'off';
            app.NeuroTractFileButton = uibutton(app.AnatPanel, 'push');
            app.NeuroTractFileButton.ButtonPushedFcn = createCallbackFcn(app, @NeuroTractFileButtonPushed, true);
            app.NeuroTractFileButton.Text = 'Select';
            app.LabelReqNeuroTracs = uilabel(app.AnatPanel);
            app.LabelReqNeuroTracs.Text = '*';
            app.ConddelayLabel = uilabel(app.AnatPanel);
            app.ConddelayLabel.Text = 'Cond. delay:';
            app.CondDelayFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CondDelayFileEditField.Editable = 'off';
            app.CondDelayFileButton = uibutton(app.AnatPanel, 'push');
            app.CondDelayFileButton.ButtonPushedFcn = createCallbackFcn(app, @CondDelayFileButtonPushed, true);
            app.CondDelayFileButton.Text = 'Select';
            app.LabelReqCondDelay = uilabel(app.AnatPanel);
            app.LabelReqCondDelay.Text = '*';
            app.ParallelprocessLabel_2 = uilabel(app.AnatomyTab);
            app.ParallelprocessLabel_2.Text = 'Use default anatomy:';
            app.UseDefaultAnatSwitch = uiswitch(app.AnatomyTab, 'slider');
            app.UseDefaultAnatSwitch.Items = {'No', 'Yes'};
            app.UseDefaultAnatSwitch.ValueChangedFcn = createCallbackFcn(app, @UseDefaultAnatSwitchValueChanged, true);
            app.UseDefaultAnatSwitch.Tooltip = {'Yes - Use default anatomy parameters'};
            app.UseDefaultAnatSwitch.Value = 'No';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelparamsButtonGroup = uibuttongroup(app.ModelTab);
            app.ModelparamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelparamsButtonGroupSelectionChanged, true);
            app.ModelparamsButtonGroup.Title = 'Model params';
            app.BayesIterationDelayLabel = uilabel(app.ModelparamsButtonGroup);
            app.BayesIterationDelayLabel.Text = 'Bayes Iteration Delay:';
            app.BayesIterationDelaySpinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIterationDelaySpinner.Limits = [30 Inf];
            app.BayesIterationDelaySpinner.Value = 30;
            app.Nrand1Label = uilabel(app.ModelparamsButtonGroup);
            app.Nrand1Label.Text = 'Nrand1:';
            app.Nrand1Spinner = uispinner(app.ModelparamsButtonGroup);
            app.Nrand1Spinner.Limits = [1 100];
            app.Nrand1Spinner.Value = 1;
            app.Nrand2Label = uilabel(app.ModelparamsButtonGroup);
            app.Nrand2Label.Text = 'Nrand2:';
            app.Nrand2Spinner = uispinner(app.ModelparamsButtonGroup);
            app.Nrand2Spinner.Limits = [1 100];
            app.Nrand2Spinner.Value = 1;
            app.BayesIter_Reg1Label = uilabel(app.ModelparamsButtonGroup);
            app.BayesIter_Reg1Label.Text = 'BayesIter_Reg1:';
            app.BayesIter_Reg1Spinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIter_Reg1Spinner.Limits = [20 100];
            app.BayesIter_Reg1Spinner.Value = 30;
            app.BayesIter_Reg2Label = uilabel(app.ModelparamsButtonGroup);
            app.BayesIter_Reg2Label.Text = 'BayesIter_Reg2:';
            app.BayesIter_Reg2Spinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIter_Reg2Spinner.Limits = [20 100];
            app.BayesIter_Reg2Spinner.Value = 30;
            app.ParallelprocessLabel_3 = uilabel(app.ModelparamsButtonGroup);
            app.ParallelprocessLabel_3.Text = 'Use default Tensor Field:';
            app.DefaultTensorFieldSwitch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.DefaultTensorFieldSwitch.Items = {'No', 'Yes'};
            app.DefaultTensorFieldSwitch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.DefaultTensorFieldSwitch.Value = 'No';
            app.ParallelprocessLabel_4 = uilabel(app.ModelparamsButtonGroup);
            app.ParallelprocessLabel_4.Text = 'Stoch1:';
            app.Stoch1Switch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.Stoch1Switch.Items = {'False', 'True'};
            app.Stoch1Switch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.Stoch1Switch.Value = 'False';
            app.Stoch2SwitchLabel = uilabel(app.ModelparamsButtonGroup);
            app.Stoch2SwitchLabel.Text = 'Stoch2:';
            app.Stoch2Switch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.Stoch2Switch.Items = {'False', 'True'};
            app.Stoch2Switch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.Stoch2Switch.Value = 'False';
            app.NumberofFrequenciesSpinnerLabel = uilabel(app.ModelparamsButtonGroup);
            app.NumberofFrequenciesSpinnerLabel.Text = 'Number of Frequencies:';
            app.NumberofFrequenciesSpinner = uispinner(app.ModelparamsButtonGroup);
            app.NumberofFrequenciesSpinner.Limits = [1 200];
            app.NumberofFrequenciesSpinner.Value = 47;
            app.GroupAnalysisPanel = uipanel(app.ModelTab);
            app.GroupAnalysisPanel.Title = 'Group Analysis';
            app.AgeRangeUITable = uitable(app.GroupAnalysisPanel);
            app.AgeRangeUITable.ColumnName = {'No.'; 'Select'; 'Start age'; 'End age'; 'Description'};
            app.AgeRangeUITable.RowName = {};
            app.AgeRangeUITable.SelectionType = 'row';
            app.AgeRangeUITable.ColumnEditable = [false true true true true];
            app.AgeRangeUITable.Multiselect = 'off';
            app.AddAgeRangeButton = uibutton(app.GroupAnalysisPanel, 'push');
            app.AddAgeRangeButton.ButtonPushedFcn = createCallbackFcn(app, @AddAgeRangeButtonPushed, true);
            app.AddAgeRangeButton.Icon = fullfile(pathToMLAPP, 'images', 'add_row.png');
            app.AddAgeRangeButton.Text = 'Add';
            app.DeleteAgeRangeButton = uibutton(app.GroupAnalysisPanel, 'push');
            app.DeleteAgeRangeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAgeRangeButtonPushed, true);
            app.DeleteAgeRangeButton.Icon = fullfile(pathToMLAPP, 'images', 'delete_row.png');
            app.DeleteAgeRangeButton.Text = 'Delete';
            app.InsertAgeRangeButton = uibutton(app.GroupAnalysisPanel, 'push');
            app.InsertAgeRangeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertAgeRangeButtonPushed, true);
            app.InsertAgeRangeButton.Icon = fullfile(pathToMLAPP, 'images', 'insert_row.png');
            app.InsertAgeRangeButton.Text = 'Insert';
            app.CancelButton = uibutton(app.SetparametersPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SetparametersPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'images', 'apply.png');
            app.ApplyButton.Text = 'Apply';
            app.ButtonBack = uibutton(app.SetparametersPanel, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = fullfile(pathToMLAPP, 'images', 'back.png');
            app.ButtonBack.Tooltip = {'Back'};
            app.ButtonBack.Text = '';
            app.ButtonNext = uibutton(app.SetparametersPanel, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Icon = fullfile(pathToMLAPP, 'images', 'next.png');
            app.ButtonNext.Tooltip = {'Next'};
            app.ButtonNext.Text = '';
            app.Showrejectedsubjects = uihyperlink(app.SetparametersPanel);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamsUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end